classdef cellClusterSplittingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        BulkrunAllZslicesPanel         matlab.ui.container.Panel
        StopButton                     matlab.ui.control.Button
        BulkRunButton                  matlab.ui.control.Button
        TuningSignificancescorePanel   matlab.ui.container.Panel
        CurrentPvalueTextArea          matlab.ui.control.TextArea
        CurrentPvalueLabel             matlab.ui.control.Label
        ThresholdSlider                matlab.ui.control.Slider
        ThresholdSliderLabel           matlab.ui.control.Label
        RunsplittingPanel              matlab.ui.container.Panel
        RunButton                      matlab.ui.control.Button
        SmoothingFactorEditField       matlab.ui.control.NumericEditField
        SmoothingFactorEditFieldLabel  matlab.ui.control.Label
        InputfilePanel                 matlab.ui.container.Panel
        loaddataButton                 matlab.ui.control.Button
        RawDataTextArea                matlab.ui.control.TextArea
        RawdataButton                  matlab.ui.control.Button
        MaskButton                     matlab.ui.control.Button
        MaskText                       matlab.ui.control.TextArea
        SliceIDEditField               matlab.ui.control.NumericEditField
        SliceIDEditFieldLabel          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ChangeContrastPanel            matlab.ui.container.Panel
        maxSlider                      matlab.ui.control.Slider
        maxSliderLabel                 matlab.ui.control.Label
        minSlider                      matlab.ui.control.Slider
        minSliderLabel                 matlab.ui.control.Label
        VisualizationPanel             matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        im % raw data
        maskAll % maskAll
        lenx
        leny
        lenz
        imCurz % the raw data of the selected Z slice
        mask_zz % the mask of the selected Z slice
        perim_zz % the initial boundary of the mask
        visualize_raw
        imSmoothed %3D smoothed data 
        prCurvature % 3D principal curvature 
        muRatioThinNeck % mu of the emperical null distribution for the ratio of thin neck obtained from one z slice
        sigmaRatioThinNeck
        boundingBoxCellzz
        imDiffAll %matrix of local difference used for variance estimation[3*3]
        imDiffAll2 % use the 16 neighbors[5*5] - [3*3]
        seedMaskAllzz % the mask of the seed region at the current Z slice
        combinedPvaluezz %the combined p-value at the current Z slice
        mask_zz_ROI_idx % the region ID of the current Z slice
        growedROICellzz
        minPvaluezz 
        maxPvaluezz
        perim_split_zz
        path 
        file
        curThres
        stopIter
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RawdataButton
        function RawdataButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.tif');
            if isequal(app.file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(app.path,app.file)]);
               app.RawDataTextArea.Value = app.file;
               imregion = im2double(tiffRead(fullfile(app.path,app.file), 16));
               imregion = (imregion - min(imregion(:)))./(max(imregion(:)) - min(imregion(:))).*255;
               app.im = imregion;
            end
            [app.lenx, app.leny, app.lenz] = size(app.im);

        end

        % Value changed function: RawDataTextArea
        function RawDataTextAreaValueChanged(app, event)
            app.RawDataTextArea.Value = app.RawDataTextArea.Value;
            
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            [file2,path2] = uigetfile('*.tif');
            if isequal(file2,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path2,file2)]);
               app.MaskText.Value = file2;
               imregion = tiffRead(fullfile(path2,file2), 8);
               imregion = double(imregion > 0);
               app.maskAll = imregion;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            img3D = app.im;
            imSmoothedtmp = zeros(size(img3D));
            for i = 1:app.lenz
                
               imSmoothedtmp(:,:,i) = imgaussfilt(img3D(:,:,i),app.SmoothingFactorEditField.Value); 
                
            end
            app.imSmoothed = imSmoothedtmp;
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message','Start inferring the null distribution');
            pause(2)
            app.prCurvature = principalCurvatureSeg_2D(app.im,app.SmoothingFactorEditField.Value); 
            zz = app.SliceIDEditField.Value;
            [app.muRatioThinNeck, app.sigmaRatioThinNeck, app.boundingBoxCellzz] = buildEmpDstrThinNeck(app.maskAll,app.imSmoothed, zz, app.lenx, app.leny);
            d.Value = .33; 
            d.Message = 'Testing using the two features';
            pause(1)
            app.imDiffAll = caldiffMap(app.im);
            app.imDiffAll2 = caldiffMap_v2(app.im);
            [app.seedMaskAllzz,app.combinedPvaluezz, app.mask_zz_ROI_idx] = testingTwoFeature(app.im,app.maskAll,app.prCurvature,app.imDiffAll,app.imDiffAll2,app.imSmoothed,...
                app.boundingBoxCellzz, zz,app.lenx, app.leny, app.muRatioThinNeck, app.sigmaRatioThinNeck);
            imCurzG = app.imSmoothed;
            imCurzG = imCurzG(:,:,zz);
            d.Value = .67; 
            d.Message = 'Region Splitting';
            pause(1)
            [app.growedROICellzz,app.minPvaluezz, app.maxPvaluezz] = regionSplit(app.mask_zz_ROI_idx,imCurzG,app.combinedPvaluezz,app.seedMaskAllzz, app.boundingBoxCellzz, app.lenx, app.leny);
            app.perim_split_zz = genPerimROI(app.growedROICellzz, app.boundingBoxCellzz, app.lenx, app.leny);
            outputgrowedROICellzz = app.growedROICellzz;
            app.curThres = 0.05; % default threshold for the p-value
            save(fullfile(app.path, [app.file,'_z',num2str(zz) ,'growedROICellzz.mat']),'outputgrowedROICellzz');
            visualizetmp = app.visualize_raw;
            visualizetmp(:,:,1) = app.perim_split_zz;
            app.visualize_raw = visualizetmp;
            imshow(app.visualize_raw,'Parent',app.UIAxes);
            d.Value = 1;
            d.Message = 'Finishing';
            pause(1)
            close(d)
            close(fig)
        end

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            img3D = app.im;
            mask3D = app.maskAll;
            app.imCurz = img3D(:,:,app.SliceIDEditField.Value);
            app.mask_zz = mask3D(:,:,app.SliceIDEditField.Value);
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Loading data',...
                'Indeterminate','on');
            drawnow

            perimBinary = bwperim(app.mask_zz);  
            app.perim_zz = perimBinary;
            visualizetmp= zeros(app.lenx, app.leny, 3);
            visualizetmp(:,:,2) = app.imCurz./255;
            visualizetmp(:,:,3) = app.perim_zz;
            app.visualize_raw = visualizetmp;
            app.perim_split_zz = zeros(app.lenx, app.leny);
            imshow(app.visualize_raw,'Parent',app.UIAxes);
            app.maxSlider.Value = 255;
            app.minSlider.Value = 0;
            close(d);
            close(fig);
            app.ThresholdSlider.Limits(1) = 0;
            app.ThresholdSlider.Limits(2) = -log10(0.0001);
            app.CurrentPvalueTextArea.Value = num2str(0.05);
        end

        % Value changed function: minSlider
        function minSliderValueChanged(app, event)
            minIns = app.minSlider.Value;
            maxIns = app.maxSlider.Value;
            imCurzShow = app.imCurz;
            imCurzShow = (imCurzShow - minIns)/(maxIns - minIns);
            visualizetmp = app.visualize_raw;
            visualizetmp(:,:,2) = imCurzShow;
            app.visualize_raw = visualizetmp;
            imshow(app.visualize_raw,'Parent',app.UIAxes);
        end

        % Value changed function: maxSlider
        function maxSliderValueChanged(app, event)
            minIns = app.minSlider.Value;
            maxIns = app.maxSlider.Value;
            imCurzShow = app.imCurz;
            imCurzShow = (imCurzShow - minIns)/(maxIns - minIns);
            visualizetmp = app.visualize_raw;
            visualizetmp(:,:,2) = imCurzShow;
            app.visualize_raw = visualizetmp;
            imshow(app.visualize_raw,'Parent',app.UIAxes);
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            app.curThres = 10^(-app.ThresholdSlider.Value);
            app.CurrentPvalueTextArea.Value = num2str(app.curThres,3);
            zz = app.SliceIDEditField.Value;
            imCurzG = app.imSmoothed;
            imCurzG = imCurzG(:,:,zz);
            [~, updatedROI] = updateROI(imCurzG, app.lenx, app.leny, app.mask_zz_ROI_idx, ...
                app.combinedPvaluezz,app.seedMaskAllzz, app.growedROICellzz, app.minPvaluezz, app.maxPvaluezz, app.curThres);
            app.perim_split_zz = genPerimROI(updatedROI, app.boundingBoxCellzz, app.lenx, app.leny);
            outputgrowedROICellzz = app.growedROICellzz;
            save(fullfile(app.path, [app.file,'_z',num2str(zz) ,'growedROICellzz.mat']),'outputgrowedROICellzz');
            visualizetmp = app.visualize_raw;
            visualizetmp(:,:,1) = app.perim_split_zz;
            app.visualize_raw = visualizetmp;
            imshow(app.visualize_raw,'Parent',app.UIAxes);
            
        end

        % Button pushed function: BulkRunButton
        function BulkRunButtonPushed(app, event)
            app.stopIter = false;
            for zz = 1:app.lenz
                fig = uifigure;
                d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message',['Start running on Z-slice #', num2str(zz)]);
                pause(2)
                    [app.seedMaskAllzz,app.combinedPvaluezz, app.mask_zz_ROI_idx, app.boundingBoxCellzz] = testingTwoFeature_bulk(app.im,app.maskAll,app.prCurvature,app.imDiffAll,app.imDiffAll2,app.imSmoothed,...
                        zz,app.lenx, app.leny, app.muRatioThinNeck, app.sigmaRatioThinNeck);
                    imCurzG = app.imSmoothed;
                    imCurzG = imCurzG(:,:,zz);

                    [app.growedROICellzz] = regionSplit_withThres(app.mask_zz_ROI_idx,imCurzG,app.combinedPvaluezz,app.seedMaskAllzz, app.boundingBoxCellzz, app.lenx, app.leny,app.curThres);
                    app.perim_split_zz = genPerimROI(app.growedROICellzz, app.boundingBoxCellzz, app.lenx, app.leny);
                    outputgrowedROICellzz = app.growedROICellzz;
                    save(fullfile(app.path, [app.file,'_z',num2str(zz) ,'growedROICellzz.mat']),'outputgrowedROICellzz'); 
                    if  (app.stopIter)
                        zz = app.lenz + 1;
                        break;
                    end

            end

            close(d)
            close(fig)

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopIter = true;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {574, 574};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {221, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputfilePanel = uipanel(app.LeftPanel);
            app.InputfilePanel.Title = 'Input file ';
            app.SliceIDEditFieldLabel = uilabel(app.InputfilePanel);
            app.SliceIDEditFieldLabel.Text = 'Slice ID';
            app.SliceIDEditField = uieditfield(app.InputfilePanel, 'numeric');
            app.SliceIDEditField.Value = 9;
            app.MaskText = uitextarea(app.InputfilePanel);
            app.MaskButton = uibutton(app.InputfilePanel, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.RawdataButton = uibutton(app.InputfilePanel, 'push');
            app.RawdataButton.ButtonPushedFcn = createCallbackFcn(app, @RawdataButtonPushed, true);
            app.RawdataButton.Text = 'Raw data';
            app.RawDataTextArea = uitextarea(app.InputfilePanel);
            app.RawDataTextArea.ValueChangedFcn = createCallbackFcn(app, @RawDataTextAreaValueChanged, true);
            app.loaddataButton = uibutton(app.InputfilePanel, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.RunsplittingPanel = uipanel(app.LeftPanel);
            app.RunsplittingPanel.Title = 'Run splitting';
            app.SmoothingFactorEditFieldLabel = uilabel(app.RunsplittingPanel);
            app.SmoothingFactorEditFieldLabel.Text = 'Smoothing Factor';
            app.SmoothingFactorEditField = uieditfield(app.RunsplittingPanel, 'numeric');
            app.SmoothingFactorEditField.Value = 1;
            app.RunButton = uibutton(app.RunsplittingPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TuningSignificancescorePanel = uipanel(app.LeftPanel);
            app.TuningSignificancescorePanel.Title = 'Tuning (Significance score)';
            app.ThresholdSliderLabel = uilabel(app.TuningSignificancescorePanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.TuningSignificancescorePanel);
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.CurrentPvalueLabel = uilabel(app.TuningSignificancescorePanel);
            app.CurrentPvalueLabel.Text = {'Current '; 'P-value'};
            app.CurrentPvalueTextArea = uitextarea(app.TuningSignificancescorePanel);
            app.BulkrunAllZslicesPanel = uipanel(app.LeftPanel);
            app.BulkrunAllZslicesPanel.Title = 'Bulk run (All Z slices)';
            app.BulkRunButton = uibutton(app.BulkrunAllZslicesPanel, 'push');
            app.BulkRunButton.ButtonPushedFcn = createCallbackFcn(app, @BulkRunButtonPushed, true);
            app.BulkRunButton.Text = 'Bulk Run';
            app.StopButton = uibutton(app.BulkrunAllZslicesPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RightPanel = uipanel(app.GridLayout);
            app.VisualizationPanel = uipanel(app.RightPanel);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ChangeContrastPanel = uipanel(app.RightPanel);
            app.ChangeContrastPanel.Title = 'Change Contrast';
            app.minSliderLabel = uilabel(app.ChangeContrastPanel);
            app.minSliderLabel.Text = 'min';
            app.minSlider = uislider(app.ChangeContrastPanel);
            app.minSlider.Limits = [0 255];
            app.minSlider.ValueChangedFcn = createCallbackFcn(app, @minSliderValueChanged, true);
            app.maxSliderLabel = uilabel(app.ChangeContrastPanel);
            app.maxSliderLabel.Text = 'max';
            app.maxSlider = uislider(app.ChangeContrastPanel);
            app.maxSlider.Limits = [0 255];
            app.maxSlider.ValueChangedFcn = createCallbackFcn(app, @maxSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellClusterSplittingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end